classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        IngresarmodeloTab               matlab.ui.container.Tab
        NumeroarticulacionesDropDownLabel  matlab.ui.control.Label
        NumeroarticulacionesDropDown    matlab.ui.control.DropDown
        ModelosPrecargadosTab           matlab.ui.container.Tab
        SeleccionarrobotLabel           matlab.ui.control.Label
        SelecModelo                     matlab.ui.control.DropDown
        ModelarButton                   matlab.ui.control.Button
        MatrizDHLabel                   matlab.ui.control.Label
        CargarButton                    matlab.ui.control.Button
        DH11                            matlab.ui.control.NumericEditField
        DH12                            matlab.ui.control.NumericEditField
        DH13                            matlab.ui.control.NumericEditField
        DH14                            matlab.ui.control.NumericEditField
        DH15                            matlab.ui.control.NumericEditField
        DH21                            matlab.ui.control.NumericEditField
        DH22                            matlab.ui.control.NumericEditField
        DH23                            matlab.ui.control.NumericEditField
        DH24                            matlab.ui.control.NumericEditField
        DH25                            matlab.ui.control.NumericEditField
        DH31                            matlab.ui.control.NumericEditField
        DH32                            matlab.ui.control.NumericEditField
        DH33                            matlab.ui.control.NumericEditField
        DH34                            matlab.ui.control.NumericEditField
        DH35                            matlab.ui.control.NumericEditField
        DH41                            matlab.ui.control.NumericEditField
        DH42                            matlab.ui.control.NumericEditField
        DH43                            matlab.ui.control.NumericEditField
        DH44                            matlab.ui.control.NumericEditField
        DH45                            matlab.ui.control.NumericEditField
        DH51                            matlab.ui.control.NumericEditField
        DH52                            matlab.ui.control.NumericEditField
        DH53                            matlab.ui.control.NumericEditField
        DH54                            matlab.ui.control.NumericEditField
        DH55                            matlab.ui.control.NumericEditField
        DH61                            matlab.ui.control.NumericEditField
        DH62                            matlab.ui.control.NumericEditField
        DH63                            matlab.ui.control.NumericEditField
        DH64                            matlab.ui.control.NumericEditField
        DH65                            matlab.ui.control.NumericEditField
        DH71                            matlab.ui.control.NumericEditField
        DH72                            matlab.ui.control.NumericEditField
        DH73                            matlab.ui.control.NumericEditField
        DH74                            matlab.ui.control.NumericEditField
        DH75                            matlab.ui.control.NumericEditField
        thetaLabel                      matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        aLabel                          matlab.ui.control.Label
        alphaLabel                      matlab.ui.control.Label
        tipoLabel                       matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        MTHTab                          matlab.ui.container.Tab
        UITableMTH                      matlab.ui.control.Table
        EulerTab                        matlab.ui.container.Tab
        UITableEuler                    matlab.ui.control.Table
        fieldq1                         matlab.ui.control.NumericEditField
        fieldq2                         matlab.ui.control.NumericEditField
        fieldq3                         matlab.ui.control.NumericEditField
        fieldq4                         matlab.ui.control.NumericEditField
        fieldq5                         matlab.ui.control.NumericEditField
        fieldq6                         matlab.ui.control.NumericEditField
        fieldq7                         matlab.ui.control.NumericEditField
        q1SliderLabel                   matlab.ui.control.Label
        q1Slider                        matlab.ui.control.Slider
        q2SliderLabel                   matlab.ui.control.Label
        q2Slider                        matlab.ui.control.Slider
        q3SliderLabel                   matlab.ui.control.Label
        q3Slider                        matlab.ui.control.Slider
        q4SliderLabel                   matlab.ui.control.Label
        q4Slider                        matlab.ui.control.Slider
        q5SliderLabel                   matlab.ui.control.Label
        q5Slider                        matlab.ui.control.Slider
        q6SliderLabel                   matlab.ui.control.Label
        q6Slider                        matlab.ui.control.Slider
        q7SliderLabel                   matlab.ui.control.Label
        q7Slider                        matlab.ui.control.Slider
        MasterenAutomticayRobticaLabel  matlab.ui.control.Label
        UniversidadPolitcnicadeMadridLabel  matlab.ui.control.Label
        DesarrolladoporAndreaFontalvoELabel  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
    end

    properties (Access = private)
        
    end
    
    properties (Access = public)
        valueNumArt
        valueq1
        valueq2
        valueq3
        valueq4
        valueq5
        valueq6
        valueq7
        DH
        DH_modelo1
        DH_modelo2
        gdl_modelo
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DH_modelo1 = [0 0.9 0.188 -1.5708 0;... % ROBOT S4 ABB 2.8
                0 0.95 0 0 0;...
                0 0 0.225 -1.5708 0;...
                0 1.705 0 1.5708 0;...
                0 0 0 -1.5708 0;...
                0 0.2 0 -1.5708 0];
            
            app.DH_modelo2 = [0 0 0 1.5708 0;... % ROBOT PUMA 560
                0 0 0.43 0 0;...
                0 0.15 0.0203 -1.5708 0;...
                0 0.43 0 1.5708 0;...
                0 0 0 -1.5708 0;...
                0 0 0 0 0];
        end

        % Button pushed function: ModelarButton
        function ModelarButtonPushed(app, event)
            
            modelo1 = 'S4 ABB 2.8';
            modelo2 = 'Puma 560';
            modelopre = app.SelecModelo.Value;
            
            mod1 = strcmp(modelo1,modelopre);
            mod2 = strcmp(modelo2,modelopre);
            
            if mod1 == 1
                joints = 6;
                app.DH=app.DH_modelo1;
            elseif mod2 ==1
                joints = 6;
                app.DH=app.DH_modelo2;
            end
            
            if app.valueNumArt ~= 0
                joints = str2double(app.valueNumArt);
            end
            
            for i = 1:joints
                L(i) = Link(app.DH(i,:));
            end
            
            q1=deg2rad(app.valueq1);q2=deg2rad(app.valueq2);q3=deg2rad(app.valueq3);
            q4=deg2rad(app.valueq4);q5=deg2rad(app.valueq5);q6=deg2rad(app.valueq6);q7=deg2rad(app.valueq7);
            
            robota = SerialLink(L,'name','robota');
            if joints == 2
                MTH = robota.fkine([q1 q2]);
                robota.plot([q1 q2])
            elseif joints == 3
                MTH = robota.fkine([q1 q2 q3]);
                robota.plot([q1 q2 q3])
            elseif joints == 4
                MTH = robota.fkine([q1 q2 q3 q4]);
                robota.plot([q1 q2 q3 q4])
            elseif joints == 5
                MTH = robota.fkine([q1 q2 q3 q4 q5]);
                robota.plot([q1 q2 q3 q4 q5])
            elseif joints == 6
                MTH = robota.fkine([q1 q2 q3 q4 q5 q6]);
                robota.plot([q1 q2 q3 q4 q5 q6])
            elseif joints == 7
                MTH = robota.fkine([q1 q2 q3 q4 q5 q6 q7]);
                robota.plot([q1 q2 q3 q4 q5 q6 q7])
            end
            
            assignin('base','MTH',MTH)
            dmth = double(evalin('base','MTH'));
            app.UITableMTH.Data = dmth;
            app.UITableEuler.Data = tr2eul(dmth,'deg');
            
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            app.valueq1 = app.q1Slider.Value;
            app.fieldq1.Value=app.valueq1;
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            app.valueq2 = app.q2Slider.Value;
            app.fieldq2.Value=app.valueq2;
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            app.valueq3 = app.q3Slider.Value;
            app.fieldq3.Value=app.valueq3;
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            app.valueq4 = app.q4Slider.Value;
            app.fieldq4.Value=app.valueq4;
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            app.valueq5 = app.q5Slider.Value;
            app.fieldq5.Value=app.valueq5;
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            app.valueq6 = app.q6Slider.Value;
            app.fieldq6.Value=app.valueq6;
        end

        % Value changed function: q7Slider
        function q7SliderValueChanged(app, event)
            app.valueq7 = app.q7Slider.Value;
            app.fieldq7.Value=app.valueq7;
        end

        % Value changed function: NumeroarticulacionesDropDown
        function NumeroarticulacionesDropDownValueChanged(app, event)
            app.valueNumArt = app.NumeroarticulacionesDropDown.Value;
            
            if app.valueNumArt ~= 0
                app.CargarButton.Enable = 'on';
            end
            
            if app.valueNumArt == '2'
                close(figure(1))
                
                app.q1Slider.Enable = 'on';app.q2Slider.Enable = 'on';app.q3Slider.Enable = 'off';
                app.q4Slider.Enable = 'off';app.q5Slider.Enable = 'off';
                app.q6Slider.Enable = 'off';app.q7Slider.Enable = 'off';
                
                app.DH11.Enable = 'on';app.DH12.Enable = 'on';app.DH13.Enable = 'on';app.DH14.Enable = 'on';app.DH15.Enable = 'on';
                app.DH21.Enable = 'on';app.DH22.Enable = 'on';app.DH23.Enable = 'on';app.DH24.Enable = 'on';app.DH25.Enable = 'on';
                app.DH31.Enable = 'off';app.DH32.Enable = 'off';app.DH33.Enable = 'off';app.DH34.Enable = 'off';app.DH35.Enable = 'off';
                app.DH41.Enable = 'off';app.DH42.Enable = 'off';app.DH43.Enable = 'off';app.DH44.Enable = 'off';app.DH45.Enable = 'off';
                app.DH51.Enable = 'off';app.DH52.Enable = 'off';app.DH53.Enable = 'off';app.DH54.Enable = 'off';app.DH55.Enable = 'off';
                app.DH61.Enable = 'off';app.DH62.Enable = 'off';app.DH63.Enable = 'off';app.DH64.Enable = 'off';app.DH65.Enable = 'off';
                app.DH71.Enable = 'off';app.DH72.Enable = 'off';app.DH73.Enable = 'off';app.DH74.Enable = 'off';app.DH75.Enable = 'off';
                
            elseif app.valueNumArt == '3'
                close(figure(1))
                
                app.q1Slider.Enable = 'on';app.q2Slider.Enable = 'on';
                app.q3Slider.Enable = 'on';app.q4Slider.Enable = 'off';
                app.q5Slider.Enable = 'off';app.q6Slider.Enable = 'off';
                app.q7Slider.Enable = 'off';
                
                app.DH11.Enable = 'on';app.DH12.Enable = 'on';app.DH13.Enable = 'on';app.DH14.Enable = 'on';app.DH15.Enable = 'on';
                app.DH21.Enable = 'on';app.DH22.Enable = 'on';app.DH23.Enable = 'on';app.DH24.Enable = 'on';app.DH25.Enable = 'on';
                app.DH31.Enable = 'on';app.DH32.Enable = 'on';app.DH33.Enable = 'on';app.DH34.Enable = 'on';app.DH35.Enable = 'on';
                app.DH41.Enable = 'off';app.DH42.Enable = 'off';app.DH43.Enable = 'off';app.DH44.Enable = 'off';app.DH45.Enable = 'off';
                app.DH51.Enable = 'off';app.DH52.Enable = 'off';app.DH53.Enable = 'off';app.DH54.Enable = 'off';app.DH55.Enable = 'off';
                app.DH61.Enable = 'off';app.DH62.Enable = 'off';app.DH63.Enable = 'off';app.DH64.Enable = 'off';app.DH65.Enable = 'off';
                app.DH71.Enable = 'off';app.DH72.Enable = 'off';app.DH73.Enable = 'off';app.DH74.Enable = 'off';app.DH75.Enable = 'off';
                
            elseif app.valueNumArt == '4'
                close(figure(1))
                app.q1Slider.Enable = 'on';app.q2Slider.Enable = 'on';
                app.q3Slider.Enable = 'on';app.q4Slider.Enable = 'on';
                app.q5Slider.Enable = 'off';app.q6Slider.Enable = 'off';
                app.q7Slider.Enable = 'off';
                
                app.DH11.Enable = 'on';app.DH12.Enable = 'on';app.DH13.Enable = 'on';app.DH14.Enable = 'on';app.DH15.Enable = 'on';
                app.DH21.Enable = 'on';app.DH22.Enable = 'on';app.DH23.Enable = 'on';app.DH24.Enable = 'on';app.DH25.Enable = 'on';
                app.DH31.Enable = 'on';app.DH32.Enable = 'on';app.DH33.Enable = 'on';app.DH34.Enable = 'on';app.DH35.Enable = 'on';
                app.DH41.Enable = 'on';app.DH42.Enable = 'on';app.DH43.Enable = 'on';app.DH44.Enable = 'on';app.DH45.Enable = 'on';
                app.DH51.Enable = 'off';app.DH52.Enable = 'off';app.DH53.Enable = 'off';app.DH54.Enable = 'off';app.DH55.Enable = 'off';
                app.DH61.Enable = 'off';app.DH62.Enable = 'off';app.DH63.Enable = 'off';app.DH64.Enable = 'off';app.DH65.Enable = 'off';
                app.DH71.Enable = 'off';app.DH72.Enable = 'off';app.DH73.Enable = 'off';app.DH74.Enable = 'off';app.DH75.Enable = 'off';
                
            elseif app.valueNumArt == '5'
                close(figure(1))
                app.q1Slider.Enable = 'on';app.q2Slider.Enable = 'on';
                app.q3Slider.Enable = 'on';app.q4Slider.Enable = 'on';
                app.q5Slider.Enable = 'on';app.q6Slider.Enable = 'off';
                app.q7Slider.Enable = 'off';
                
                app.DH11.Enable = 'on';app.DH12.Enable = 'on';app.DH13.Enable = 'on';app.DH14.Enable = 'on';app.DH15.Enable = 'on';
                app.DH21.Enable = 'on';app.DH22.Enable = 'on';app.DH23.Enable = 'on';app.DH24.Enable = 'on';app.DH25.Enable = 'on';
                app.DH31.Enable = 'on';app.DH32.Enable = 'on';app.DH33.Enable = 'on';app.DH34.Enable = 'on';app.DH35.Enable = 'on';
                app.DH41.Enable = 'on';app.DH42.Enable = 'on';app.DH43.Enable = 'on';app.DH44.Enable = 'on';app.DH45.Enable = 'on';
                app.DH51.Enable = 'on';app.DH52.Enable = 'on';app.DH53.Enable = 'on';app.DH54.Enable = 'on';app.DH55.Enable = 'on';
                app.DH61.Enable = 'off';app.DH62.Enable = 'off';app.DH63.Enable = 'off';app.DH64.Enable = 'off';app.DH65.Enable = 'off';
                app.DH71.Enable = 'off';app.DH72.Enable = 'off';app.DH73.Enable = 'off';app.DH74.Enable = 'off';app.DH75.Enable = 'off';
                
            elseif app.valueNumArt == '6'
                close(figure(1))
                app.q1Slider.Enable = 'on';app.q2Slider.Enable = 'on';
                app.q3Slider.Enable = 'on';app.q4Slider.Enable = 'on';
                app.q5Slider.Enable = 'on';app.q6Slider.Enable = 'on';
                app.q7Slider.Enable = 'off';
                
                app.DH11.Enable = 'on';app.DH12.Enable = 'on';app.DH13.Enable = 'on';app.DH14.Enable = 'on';app.DH15.Enable = 'on';
                app.DH21.Enable = 'on';app.DH22.Enable = 'on';app.DH23.Enable = 'on';app.DH24.Enable = 'on';app.DH25.Enable = 'on';
                app.DH31.Enable = 'on';app.DH32.Enable = 'on';app.DH33.Enable = 'on';app.DH34.Enable = 'on';app.DH35.Enable = 'on';
                app.DH41.Enable = 'on';app.DH42.Enable = 'on';app.DH43.Enable = 'on';app.DH44.Enable = 'on';app.DH45.Enable = 'on';
                app.DH51.Enable = 'on';app.DH52.Enable = 'on';app.DH53.Enable = 'on';app.DH54.Enable = 'on';app.DH55.Enable = 'on';
                app.DH61.Enable = 'on';app.DH62.Enable = 'on';app.DH63.Enable = 'on';app.DH64.Enable = 'on';app.DH65.Enable = 'on';
                app.DH71.Enable = 'off';app.DH72.Enable = 'off';app.DH73.Enable = 'off';app.DH74.Enable = 'off';app.DH75.Enable = 'off';
                
            elseif app.valueNumArt == '7'
                close(figure(1))
                app.q1Slider.Enable = 'on';app.q2Slider.Enable = 'on';
                app.q3Slider.Enable = 'on';app.q4Slider.Enable = 'on';
                app.q5Slider.Enable = 'on';app.q6Slider.Enable = 'on';
                app.q7Slider.Enable = 'on';
                
                app.DH11.Enable = 'on';app.DH12.Enable = 'on';app.DH13.Enable = 'on';app.DH14.Enable = 'on';app.DH15.Enable = 'on';
                app.DH21.Enable = 'on';app.DH22.Enable = 'on';app.DH23.Enable = 'on';app.DH24.Enable = 'on';app.DH25.Enable = 'on';
                app.DH31.Enable = 'on';app.DH32.Enable = 'on';app.DH33.Enable = 'on';app.DH34.Enable = 'on';app.DH35.Enable = 'on';
                app.DH41.Enable = 'on';app.DH42.Enable = 'on';app.DH43.Enable = 'on';app.DH44.Enable = 'on';app.DH45.Enable = 'on';
                app.DH51.Enable = 'on';app.DH52.Enable = 'on';app.DH53.Enable = 'on';app.DH54.Enable = 'on';app.DH55.Enable = 'on';
                app.DH61.Enable = 'on';app.DH62.Enable = 'on';app.DH63.Enable = 'on';app.DH64.Enable = 'on';app.DH65.Enable = 'on';
                app.DH71.Enable = 'on';app.DH72.Enable = 'on';app.DH73.Enable = 'on';app.DH74.Enable = 'on';app.DH75.Enable = 'on';
            end
            
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            %             app.tablaDH = readtable('DH.xlsx');
            %             app.UITableDH.Data = app.tablaDH;
            
            app.DH = [app.DH11.Value,app.DH12.Value,app.DH13.Value,app.DH14.Value,app.DH15.Value;...
                app.DH21.Value,app.DH22.Value,app.DH23.Value,app.DH24.Value,app.DH25.Value;...
                app.DH31.Value,app.DH32.Value,app.DH33.Value,app.DH34.Value,app.DH35.Value;...
                app.DH41.Value,app.DH42.Value,app.DH43.Value,app.DH44.Value,app.DH45.Value;...
                app.DH51.Value,app.DH52.Value,app.DH53.Value,app.DH54.Value,app.DH55.Value;...
                app.DH61.Value,app.DH62.Value,app.DH63.Value,app.DH64.Value,app.DH65.Value;...
                app.DH71.Value,app.DH72.Value,app.DH73.Value,app.DH74.Value,app.DH75.Value];
            
        end

        % Value changed function: SelecModelo
        function SelecModeloValueChanged(app, event)
            close(figure(1))
            
            app.valueNumArt = 0;
            
            app.q1Slider.Enable = 'on';app.q2Slider.Enable = 'on';
            app.q3Slider.Enable = 'on';app.q4Slider.Enable = 'on';
            app.q5Slider.Enable = 'on';app.q6Slider.Enable = 'on';
            app.q7Slider.Enable = 'off';
            
            app.DH11.Enable = 'off';app.DH12.Enable = 'off';app.DH13.Enable = 'off';app.DH14.Enable = 'off';app.DH15.Enable = 'off';
            app.DH21.Enable = 'off';app.DH22.Enable = 'off';app.DH23.Enable = 'off';app.DH24.Enable = 'off';app.DH25.Enable = 'off';
            app.DH31.Enable = 'off';app.DH32.Enable = 'off';app.DH33.Enable = 'off';app.DH34.Enable = 'off';app.DH35.Enable = 'off';
            app.DH41.Enable = 'off';app.DH42.Enable = 'off';app.DH43.Enable = 'off';app.DH44.Enable = 'off';app.DH45.Enable = 'off';
            app.DH51.Enable = 'off';app.DH52.Enable = 'off';app.DH53.Enable = 'off';app.DH54.Enable = 'off';app.DH55.Enable = 'off';
            app.DH61.Enable = 'off';app.DH62.Enable = 'off';app.DH63.Enable = 'off';app.DH64.Enable = 'off';app.DH65.Enable = 'off';
            app.DH71.Enable = 'off';app.DH72.Enable = 'off';app.DH73.Enable = 'off';app.DH74.Enable = 'off';app.DH75.Enable = 'off';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Modelado Cinematico de Robots';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IngresarmodeloTab = uitab(app.TabGroup);
            app.IngresarmodeloTab.Title = 'Ingresar modelo';
            app.NumeroarticulacionesDropDownLabel = uilabel(app.IngresarmodeloTab);
            app.NumeroarticulacionesDropDownLabel.Text = 'Numero articulaciones';
            app.NumeroarticulacionesDropDown = uidropdown(app.IngresarmodeloTab);
            app.NumeroarticulacionesDropDown.Items = {'Elegir', '2', '3', '4', '5', '6', '7'};
            app.NumeroarticulacionesDropDown.ValueChangedFcn = createCallbackFcn(app, @NumeroarticulacionesDropDownValueChanged, true);
            app.NumeroarticulacionesDropDown.Value = 'Elegir';
            app.ModelosPrecargadosTab = uitab(app.TabGroup);
            app.ModelosPrecargadosTab.Title = 'Modelos Precargados';
            app.SeleccionarrobotLabel = uilabel(app.ModelosPrecargadosTab);
            app.SeleccionarrobotLabel.Text = 'Seleccionar robot';
            app.SelecModelo = uidropdown(app.ModelosPrecargadosTab);
            app.SelecModelo.Items = {'Elegir', 'S4 ABB 2.8', 'Puma 560'};
            app.SelecModelo.ValueChangedFcn = createCallbackFcn(app, @SelecModeloValueChanged, true);
            app.SelecModelo.Value = 'Elegir';
            app.ModelarButton = uibutton(app.UIFigure, 'push');
            app.ModelarButton.ButtonPushedFcn = createCallbackFcn(app, @ModelarButtonPushed, true);
            app.ModelarButton.Text = 'Modelar';
            app.MatrizDHLabel = uilabel(app.UIFigure);
            app.MatrizDHLabel.Text = 'Matriz DH';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Enable = 'off';
            app.CargarButton.Text = 'Cargar';
            app.DH11 = uieditfield(app.UIFigure, 'numeric');
            app.DH11.Enable = 'off';
            app.DH12 = uieditfield(app.UIFigure, 'numeric');
            app.DH12.Enable = 'off';
            app.DH13 = uieditfield(app.UIFigure, 'numeric');
            app.DH13.Enable = 'off';
            app.DH14 = uieditfield(app.UIFigure, 'numeric');
            app.DH14.Enable = 'off';
            app.DH15 = uieditfield(app.UIFigure, 'numeric');
            app.DH15.Enable = 'off';
            app.DH21 = uieditfield(app.UIFigure, 'numeric');
            app.DH21.Enable = 'off';
            app.DH22 = uieditfield(app.UIFigure, 'numeric');
            app.DH22.Enable = 'off';
            app.DH23 = uieditfield(app.UIFigure, 'numeric');
            app.DH23.Enable = 'off';
            app.DH24 = uieditfield(app.UIFigure, 'numeric');
            app.DH24.Enable = 'off';
            app.DH25 = uieditfield(app.UIFigure, 'numeric');
            app.DH25.Enable = 'off';
            app.DH31 = uieditfield(app.UIFigure, 'numeric');
            app.DH31.Enable = 'off';
            app.DH32 = uieditfield(app.UIFigure, 'numeric');
            app.DH32.Enable = 'off';
            app.DH33 = uieditfield(app.UIFigure, 'numeric');
            app.DH33.Enable = 'off';
            app.DH34 = uieditfield(app.UIFigure, 'numeric');
            app.DH34.Enable = 'off';
            app.DH35 = uieditfield(app.UIFigure, 'numeric');
            app.DH35.Enable = 'off';
            app.DH41 = uieditfield(app.UIFigure, 'numeric');
            app.DH41.Enable = 'off';
            app.DH42 = uieditfield(app.UIFigure, 'numeric');
            app.DH42.Enable = 'off';
            app.DH43 = uieditfield(app.UIFigure, 'numeric');
            app.DH43.Enable = 'off';
            app.DH44 = uieditfield(app.UIFigure, 'numeric');
            app.DH44.Enable = 'off';
            app.DH45 = uieditfield(app.UIFigure, 'numeric');
            app.DH45.Enable = 'off';
            app.DH51 = uieditfield(app.UIFigure, 'numeric');
            app.DH51.Enable = 'off';
            app.DH52 = uieditfield(app.UIFigure, 'numeric');
            app.DH52.Enable = 'off';
            app.DH53 = uieditfield(app.UIFigure, 'numeric');
            app.DH53.Enable = 'off';
            app.DH54 = uieditfield(app.UIFigure, 'numeric');
            app.DH54.Enable = 'off';
            app.DH55 = uieditfield(app.UIFigure, 'numeric');
            app.DH55.Enable = 'off';
            app.DH61 = uieditfield(app.UIFigure, 'numeric');
            app.DH61.Enable = 'off';
            app.DH62 = uieditfield(app.UIFigure, 'numeric');
            app.DH62.Enable = 'off';
            app.DH63 = uieditfield(app.UIFigure, 'numeric');
            app.DH63.Enable = 'off';
            app.DH64 = uieditfield(app.UIFigure, 'numeric');
            app.DH64.Enable = 'off';
            app.DH65 = uieditfield(app.UIFigure, 'numeric');
            app.DH65.Enable = 'off';
            app.DH71 = uieditfield(app.UIFigure, 'numeric');
            app.DH71.Enable = 'off';
            app.DH72 = uieditfield(app.UIFigure, 'numeric');
            app.DH72.Enable = 'off';
            app.DH73 = uieditfield(app.UIFigure, 'numeric');
            app.DH73.Enable = 'off';
            app.DH74 = uieditfield(app.UIFigure, 'numeric');
            app.DH74.Enable = 'off';
            app.DH75 = uieditfield(app.UIFigure, 'numeric');
            app.DH75.Enable = 'off';
            app.thetaLabel = uilabel(app.UIFigure);
            app.thetaLabel.Text = 'theta';
            app.dLabel = uilabel(app.UIFigure);
            app.dLabel.Text = 'd';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.alphaLabel = uilabel(app.UIFigure);
            app.alphaLabel.Text = 'alpha';
            app.tipoLabel = uilabel(app.UIFigure);
            app.tipoLabel.Text = 'tipo';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MTHTab = uitab(app.TabGroup2);
            app.MTHTab.Title = 'MTH';
            app.UITableMTH = uitable(app.MTHTab);
            app.UITableMTH.ColumnName = {''; ''; ''; ''};
            app.UITableMTH.RowName = {};
            app.UITableMTH.ColumnEditable = [false true true false];
            app.EulerTab = uitab(app.TabGroup2);
            app.EulerTab.Title = 'Euler';
            app.UITableEuler = uitable(app.EulerTab);
            app.UITableEuler.ColumnName = {''; ''; ''; ''};
            app.UITableEuler.RowName = {};
            app.UITableEuler.ColumnEditable = [false true true false];
            app.fieldq1 = uieditfield(app.UIFigure, 'numeric');
            app.fieldq1.Limits = [-180 180];
            app.fieldq1.Editable = 'off';
            app.fieldq2 = uieditfield(app.UIFigure, 'numeric');
            app.fieldq2.Limits = [-180 180];
            app.fieldq2.Editable = 'off';
            app.fieldq3 = uieditfield(app.UIFigure, 'numeric');
            app.fieldq3.Limits = [-180 180];
            app.fieldq3.Editable = 'off';
            app.fieldq4 = uieditfield(app.UIFigure, 'numeric');
            app.fieldq4.Limits = [-180 180];
            app.fieldq4.Editable = 'off';
            app.fieldq5 = uieditfield(app.UIFigure, 'numeric');
            app.fieldq5.Limits = [-180 180];
            app.fieldq5.Editable = 'off';
            app.fieldq6 = uieditfield(app.UIFigure, 'numeric');
            app.fieldq6.Limits = [-180 180];
            app.fieldq6.Editable = 'off';
            app.fieldq7 = uieditfield(app.UIFigure, 'numeric');
            app.fieldq7.Limits = [-180 180];
            app.fieldq7.Editable = 'off';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Enable = 'off';
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.MajorTickLabels = {'-180', '', '', '', '', '', '180'};
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.Enable = 'off';
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Enable = 'off';
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.MajorTickLabels = {'-180', '', '', '', '', '', '180'};
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q2Slider.Enable = 'off';
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Enable = 'off';
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-180 180];
            app.q3Slider.MajorTickLabels = {'-180', '', '', '', '', '', '180'};
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q3Slider.Enable = 'off';
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Enable = 'off';
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.MajorTickLabels = {'-180', '', '', '', '', '', '180'};
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q4Slider.Enable = 'off';
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Enable = 'off';
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-180 180];
            app.q5Slider.MajorTickLabels = {'-180', '', '', '', '', '', '180'};
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q5Slider.Enable = 'off';
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Enable = 'off';
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-180 180];
            app.q6Slider.MajorTickLabels = {'-180', '', '', '', '', '', '180'};
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q6Slider.Enable = 'off';
            app.q7SliderLabel = uilabel(app.UIFigure);
            app.q7SliderLabel.Enable = 'off';
            app.q7SliderLabel.Text = 'q7';
            app.q7Slider = uislider(app.UIFigure);
            app.q7Slider.Limits = [-180 180];
            app.q7Slider.MajorTickLabels = {'-180', '', '', '', '', '', '180'};
            app.q7Slider.ValueChangedFcn = createCallbackFcn(app, @q7SliderValueChanged, true);
            app.q7Slider.Enable = 'off';
            app.MasterenAutomticayRobticaLabel = uilabel(app.UIFigure);
            app.MasterenAutomticayRobticaLabel.Text = 'Master en Automática y Robótica  |';
            app.UniversidadPolitcnicadeMadridLabel = uilabel(app.UIFigure);
            app.UniversidadPolitcnicadeMadridLabel.Text = 'Universidad Politécnica de Madrid';
            app.DesarrolladoporAndreaFontalvoELabel = uilabel(app.UIFigure);
            app.DesarrolladoporAndreaFontalvoELabel.Text = ' |  Desarrollado por: Andrea Fontalvo E.';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'ETSII.gif';
            app.Button.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end